classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        EntrenamientoredTab          matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        EntrenarredButton            matlab.ui.control.Button
        GridLayout3                  matlab.ui.container.GridLayout
        ComenzarentrenamientoButton  matlab.ui.control.Button
        IntroduceunnombreLabel       matlab.ui.control.Label
        NombrearchivodatosEditFieldLabel  matlab.ui.control.Label
        NombrearchivodatosEditField  matlab.ui.control.EditField
        BuscarButton                 matlab.ui.control.Button
        ReconocimientoaccionesTab    matlab.ui.container.Tab
        ComenzarcapturadeaccionesButton  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
             %Busca el archivo en la carpeta MobileSensorData
            if exist("MobileSensorData/"+app.NombrearchivodatosEditField.Value+".mat", "file")
                app.IntroduceunnombreLabel.Text = "Archivo encontrado";
                app.IntroduceunnombreLabel.FontColor = "0.47,0.67,0.19";
                app.ComenzarentrenamientoButton.Enable=1;
            else
                app.IntroduceunnombreLabel.Text = "Archivo NO encontrado";
                app.IntroduceunnombreLabel.FontColor = "1.00,0.00,0.00";
                app.ComenzarentrenamientoButton.Enable=0;
            end
        end

        % Button pushed function: ComenzarentrenamientoButton
        function ComenzarentrenamientoButtonPushed(app, event)
            %Inicia el entrenamiento con el archivo de datos seleccionado
           %S = load ("MobileSensorData/"+app.NombrearchivodatosEditField.Value+".mat");
           S = load ("HumanActivityTrain");
           numFeatures = 3;
           numHiddenUnits = 200;
           numClasses = 5;
           layers = [ ...
            sequenceInputLayer(numFeatures)
            lstmLayer(numHiddenUnits,'OutputMode','sequence')
            fullyConnectedLayer(numClasses)
            softmaxLayer
            classificationLayer];

        options = trainingOptions('adam', ...
            'MaxEpochs',60, ...
            'GradientThreshold',2, ...
            'Verbose',0, ...
            'Plots','training-progress');
        
        net = trainNetwork(XTrain,YTrain,layers,options);
        save(fullfile(pwd, 'src','red','netLSTMTFG'), 'net');
        end

        % Size changed function: ReconocimientoaccionesTab
        function ReconocimientoaccionesTabSizeChanged(app, event)
            position = app.ReconocimientoaccionesTab.Position;
            InicioLSTMTFG;
        end

        % Button pushed function: EntrenarredButton
        function EntrenarredButtonPushed(app, event)
            entrenamientoRed;
        end

        % Button pushed function: ComenzarcapturadeaccionesButton
        function ComenzarcapturadeaccionesButtonPushed(app, event)
            ActividadLSTMTFG;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenamientoredTab = uitab(app.TabGroup);
            app.EntrenamientoredTab.Title = 'Entrenamiento red';
            app.GridLayout = uigridlayout(app.EntrenamientoredTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EntrenarredButton = uibutton(app.GridLayout2, 'push');
            app.EntrenarredButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarredButtonPushed, true);
            app.EntrenarredButton.Text = 'Entrenar red';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ComenzarentrenamientoButton = uibutton(app.GridLayout3, 'push');
            app.ComenzarentrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarentrenamientoButtonPushed, true);
            app.ComenzarentrenamientoButton.Enable = 'off';
            app.ComenzarentrenamientoButton.Text = 'Comenzar entrenamiento';
            app.IntroduceunnombreLabel = uilabel(app.GridLayout3);
            app.IntroduceunnombreLabel.Text = 'Introduce un nombre';
            app.NombrearchivodatosEditFieldLabel = uilabel(app.GridLayout3);
            app.NombrearchivodatosEditFieldLabel.Text = 'Nombre archivo datos';
            app.NombrearchivodatosEditField = uieditfield(app.GridLayout3, 'text');
            app.BuscarButton = uibutton(app.GridLayout3, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.ReconocimientoaccionesTab = uitab(app.TabGroup);
            app.ReconocimientoaccionesTab.SizeChangedFcn = createCallbackFcn(app, @ReconocimientoaccionesTabSizeChanged, true);
            app.ReconocimientoaccionesTab.Title = 'Reconocimiento acciones';
            app.ComenzarcapturadeaccionesButton = uibutton(app.ReconocimientoaccionesTab, 'push');
            app.ComenzarcapturadeaccionesButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarcapturadeaccionesButtonPushed, true);
            app.ComenzarcapturadeaccionesButton.Text = 'Comenzar captura de acciones';
            app.Label = uilabel(app.ReconocimientoaccionesTab);
            app.Label.Text = 'Antes de comenzar la captura de acciones asegurese de activar los sensores en el dispositivo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end